classdef app2_temp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        PLANTHEALTHLampLabel         matlab.ui.control.Label
        PLANTHEALTHLamp              matlab.ui.control.Lamp
        PlantTrackerLabel            matlab.ui.control.Label
        NOWTextArea_2Label           matlab.ui.control.Label
        NOWTextArea_2                matlab.ui.control.TextArea
        Slider                       matlab.ui.control.Slider
        Button                       matlab.ui.control.StateButton
        STARTTextAreaLabel           matlab.ui.control.Label
        STARTTextArea                matlab.ui.control.TextArea
        PLANTHEALTHLampLabel_2       matlab.ui.control.Label
        PLANTHEALTHLampLabel_3       matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        TextArea_2                   matlab.ui.control.TextArea
        Label                        matlab.ui.control.Label
        TextArea_3                   matlab.ui.control.TextArea
        ThresholdLabel               matlab.ui.control.Label
        NowLabel                     matlab.ui.control.Label
        AllGoodLampLabel             matlab.ui.control.Label
        AllGoodLamp                  matlab.ui.control.Lamp
        PlantneedswaterNOWLampLabel  matlab.ui.control.Label
        PlantneedswaterNOWLamp       matlab.ui.control.Lamp
        PlanttypeDropDownLabel       matlab.ui.control.Label
        PlanttypeDropDown            matlab.ui.control.DropDown
        NameofplantEditFieldLabel    matlab.ui.control.Label
        NameofplantEditField         matlab.ui.control.EditField
        PlantspeciesDropDownLabel    matlab.ui.control.Label
        PlantspeciesDropDown         matlab.ui.control.DropDown
        Label_2                      matlab.ui.control.Label
    end

    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            
            % Time point of interest as indicated by slider 
            timepoint = round(app.Slider.Value);            
                        
            % Load data
            Data = load('NodeMCU2.csv');
            
            % Find missing values
            Data_Hrgb = Data;
            Idx_missing = Data_Hrgb == 0;
            
            Data_Hrgb(Idx_missing) = NaN;
            
            % Impute missing values
            Data_Hrgb_filled = fillmissing(Data_Hrgb,'linear');
            
            % Find R G B ratios
            Sum_RGB = sum(Data_Hrgb_filled(:,2:4),2);
            R = round(255*Data_Hrgb_filled(:,2)./Sum_RGB)/255;
            G = round(255*Data_Hrgb_filled(:,3)./Sum_RGB)/255;
            B = round(255*Data_Hrgb_filled(:,4)./Sum_RGB)/255;
            
            % Fit ARIMA model for forecasting  
            Nsteps = 24;
            Mdl_rgb = arima(2,0,0);
            EstmdlR = estimate(Mdl_rgb,R);
            EstmdlG = estimate(Mdl_rgb,G);
            EstmdlB = estimate(Mdl_rgb,B);
            
            % forecast 24 hours ahead 
            [Rf,Rmse] = forecast(EstmdlR,Nsteps);
            [Gf,Gmse] = forecast(EstmdlG,Nsteps);
            [Bf,Bmse] = forecast(EstmdlB,Nsteps);
            
            % Plot colour change over time 
            RR = [R;Rf];
            GG = [G;Gf];
            BB = [B;Bf];
            
            for ii = 1:length(RR)
                H = histogram(app.UIAxes,ii,1);
                H.FaceColor = [RR(ii) GG(ii) BB(ii)];
                H.EdgeColor = 'none';
                H.FaceAlpha = 1;
                if ii == timepoint
                    H.EdgeColor = 'k';
                    H.FaceColor = 'w';
                    H.FaceAlpha = 1;
                end
                hold(app.UIAxes,'on')
            end
            axis(app.UIAxes,[0.5 length(RR) 0 1])
            
            % Determine a combined RGB score 
            RGB = [RR*255 GG*255 BB*255];
            combined_rgb = 65536*RGB(:,1) + 256*RGB(:,2) + RGB(:,3);
            
            % Set threshold value to determine when to water 
            Thresh = 5268556;
            %5203020
            % Change lamp colour to red if the plant needs watering at timepoint of interest 
            if combined_rgb(timepoint) <= Thresh
                app.PLANTHEALTHLamp.Color = [1 0 0];
            else 
                app.PLANTHEALTHLamp.Color = [0 1 0];
            end
            
            % Indicate the time at which the colour crosses the threshold on the graph 
            thresh_loc = find(combined_rgb <= Thresh,1,'first');
            plot(app.UIAxes,thresh_loc,(0:0.1:1)','w.')
            
            % Patches indicating the colour at timepoint for interest and colour at the start
            app.NOWTextArea_2.BackgroundColor = [RR(timepoint) GG(timepoint) BB(timepoint)];
            app.STARTTextArea.BackgroundColor = [R(1) G(1) B(1)];


        end

        % Callback function
        function LoadandprocessdataSwitchValueChanged(app, event)
             
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time steps (hours)')
            app.UIAxes.XLim = [0 192];
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLim = [0 1];
            app.UIAxes.YLimMode = 'manual';
            app.PLANTHEALTHLampLabel = uilabel(app.UIFigure);
            app.PLANTHEALTHLampLabel.Text = 'PLANT HEALTH';
            app.PLANTHEALTHLamp = uilamp(app.UIFigure);
            app.PlantTrackerLabel = uilabel(app.UIFigure);
            app.PlantTrackerLabel.Text = 'Plant Tracker';
            app.NOWTextArea_2Label = uilabel(app.UIFigure);
            app.NOWTextArea_2Label.Text = 'NOW';
            app.NOWTextArea_2 = uitextarea(app.UIFigure);
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 214];
            app.Slider.MajorTicks = [];
            app.Slider.MajorTickLabels = {};
            app.Slider.MinorTicks = [];
            app.Slider.Value = 1;
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = '';
            app.STARTTextAreaLabel = uilabel(app.UIFigure);
            app.STARTTextAreaLabel.Text = 'START';
            app.STARTTextArea = uitextarea(app.UIFigure);
            app.PLANTHEALTHLampLabel_2 = uilabel(app.UIFigure);
            app.PLANTHEALTHLampLabel_2.Text = 'MONITOR';
            app.PLANTHEALTHLampLabel_3 = uilabel(app.UIFigure);
            app.PLANTHEALTHLampLabel_3.Text = 'LOAD & PROCESS';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '- - - - - - - - - - - - - - - - - - - - - - - - - - -  ';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.Editable = 'off';
            app.ThresholdLabel = uilabel(app.UIFigure);
            app.ThresholdLabel.Text = 'Threshold';
            app.NowLabel = uilabel(app.UIFigure);
            app.NowLabel.Text = 'Now';
            app.AllGoodLampLabel = uilabel(app.UIFigure);
            app.AllGoodLampLabel.Text = 'All Good';
            app.AllGoodLamp = uilamp(app.UIFigure);
            app.AllGoodLamp.Enable = 'off';
            app.PlantneedswaterNOWLampLabel = uilabel(app.UIFigure);
            app.PlantneedswaterNOWLampLabel.Text = 'Plant needs water NOW!';
            app.PlantneedswaterNOWLamp = uilamp(app.UIFigure);
            app.PlantneedswaterNOWLamp.Enable = 'off';
            app.PlanttypeDropDownLabel = uilabel(app.UIFigure);
            app.PlanttypeDropDownLabel.Text = 'Plant type';
            app.PlanttypeDropDown = uidropdown(app.UIFigure);
            app.PlanttypeDropDown.Items = {'Indoor', 'Outdoor'};
            app.PlanttypeDropDown.Enable = 'off';
            app.PlanttypeDropDown.Value = 'Indoor';
            app.NameofplantEditFieldLabel = uilabel(app.UIFigure);
            app.NameofplantEditFieldLabel.Text = 'Name of plant';
            app.NameofplantEditField = uieditfield(app.UIFigure, 'text');
            app.NameofplantEditField.Editable = 'off';
            app.NameofplantEditField.Enable = 'off';
            app.NameofplantEditField.Value = 'Bob';
            app.PlantspeciesDropDownLabel = uilabel(app.UIFigure);
            app.PlantspeciesDropDownLabel.Text = 'Plant species';
            app.PlantspeciesDropDown = uidropdown(app.UIFigure);
            app.PlantspeciesDropDown.Items = {'Basil'};
            app.PlantspeciesDropDown.Enable = 'off';
            app.PlantspeciesDropDown.Value = 'Basil';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '- - -  ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = app2_temp

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end